classdef Check_Ripple_Delta_Spindle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EventTypeDropDown       matlab.ui.control.DropDown
        EventTypeDropDownLabel  matlab.ui.control.Label
        UIScatter               matlab.ui.control.UIAxes
        UI_LFP                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        Ploted_LPF % Defines which data will be ploted
        MPFC_data % mPFC data
        CA1_data % CA1_data
        CA1_data_filtered
        CA1_data_filtered_theta
        All_Sort % All_Sort data
        Parameters % Save the events parameters (scatter = zero-crossing, SD/duration, integral)
        Event_matrix % Struct with ripple, delta, spindle matrices
        Event_timestamps % Matrix with [beginning peak end; ....] of each selected event (either ripple, delta or spindle)
        Selected_Event_Type_Epochs % Has the matrix of one of event types epochs indices
        X_axis_value % Vector values (X)
        Y_axis_value % Vector values (Y)
        Grouped_axis_values % Matrix = grouped vector values
        X_axis_name % X axis label
        Y_axis_name % Y axis label
        Scatter_periods % List of index for nREM periods
        Selected_LFP % LFP selected to be ploted
        
    end
    
    methods (Access = private)
        
        % Update the plots
        function update_plots(app,selected_period_lfp,selected_period_scatter,selected_event_timestamps)
            % selected_period = absolute index of the epoch which is going to be plotted
            
            time_vec = linspace(size(app.Selected_LFP,2)/2*(-1),size(app.Selected_LFP,2)/2,size(app.Selected_LFP,2));  % Time vector
            event_vec = selected_event_timestamps(1):selected_event_timestamps(3); % Event vector
            
            % Plot the LFP
            plot(app.UI_LFP,time_vec,app.Selected_LFP(selected_period_lfp,:))
            hold(app.UI_LFP, 'on' )
            
            % Change the xlim accordingly
%             app.UI_LFP.XLim = [selected_event_timestamps(2)-400 ...
%                 selected_event_timestamps(2)+400];
            app.UI_LFP.YLim = [-1000 1000];
            
            % Plot the event
            plot(app.UI_LFP,time_vec(event_vec),app.Selected_LFP(selected_period_lfp,event_vec),'r')

            % Plot the filtered signal if it is ripple inspection
            if strcmp(app.EventTypeDropDown.Value,'Ripple')
                plot(app.UI_LFP,time_vec,-500+app.CA1_data_filtered(selected_period_lfp,:))
                plot(app.UI_LFP,time_vec(event_vec),-500+app.CA1_data_filtered(selected_period_lfp,event_vec),'r')
            end

            hold(app.UI_LFP, 'off' )

            
            % Plot the Scatter
            scatter(app.UIScatter,app.X_axis_value,app.Y_axis_value)  % All the events
            hold(app.UIScatter, 'on' )
            scatter(app.UIScatter,app.X_axis_value(selected_period_scatter),app.Y_axis_value(selected_period_scatter))    % Only the selected event
            hold(app.UIScatter, 'off' )
            
            drawnow % Update the plots
        end        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, all_Sort, mPFC_LFP, CA1_LFP, ripple_matrix, delta_matrix, spindle_matrix)
            % The startup will need the classification vector, the LFP data (CA1 and mPFC)
            % If one of those LFP data has not been handed, the dependent events will not be considered
            
            %% TEMPORARY UPLOAD THE DATA
%             load('C:\Users\ikaro\OneDrive\Documentos\AnyDesk Dowload\data_teste_interface.mat',"ripple_result_block","delta_wave_final","spindle_final")
%             ripple_matrix = ripple_result_block;
%             delta_matrix = delta_wave_final;
%             spindle_matrix = spindle_final;
%             load('C:\Users\ikaro\OneDrive\Documentos\MATLAB\Dados - Alan\R16_processed.mat',"LFP1");
%             mPFC_LFP = LFP1;
%             clear LFP1
%             load('C:\Users\ikaro\OneDrive\Documentos\MATLAB\Dados - Alan\R16_processed.mat',"LFP2");
%             CA1_LFP = LFP2;
%             clear LFP2
            
            % Ripple
            % Pre-allocate the parameters
            app.Parameters.ripple.zero_crossing = zeros(size(ripple_matrix,1),1);
            app.Parameters.ripple.sd_duration = zeros(size(ripple_matrix,1),1);
            
            app.CA1_data_filtered = eegfilt2(CA1_LFP,1000,140,[]);
            app.CA1_data_filtered = eegfilt2(app.CA1_data_filtered,1000,[],220);
            
            % Check if the user informed the ripple matrix
            if ~isempty(ripple_matrix)
                % Loop (each detected ripple)
                for rp = 1:size(ripple_matrix,1)
                    % Calc zero-crossing
                    app.Parameters.ripple.zero_crossing(rp) = ZCR(app.CA1_data_filtered(ripple_matrix(rp,1),ripple_matrix(rp,2):ripple_matrix(rp,4)));

                    % Calc SD/duration
                    app.Parameters.ripple.sd_duration(rp) = std(app.CA1_data_filtered(ripple_matrix(rp,1),ripple_matrix(rp,2):ripple_matrix(rp,4)));%...
                    %/ length(ripple_matrix(rp,2):ripple_matrix(rp,4));

                    % Calc RMS
                    app.Parameters.ripple.RMS(rp) = rms(app.CA1_data_filtered(ripple_matrix(rp,1),ripple_matrix(rp,2):ripple_matrix(rp,4)));%...

                    % Amplitude
                    %                     max_v = max(CA1_LFP(ripple_matrix(rp,1),ripple_matrix(rp,2):ripple_matrix(rp,4)));
                    %                     min_v = min(CA1_LFP(ripple_matrix(rp,1),ripple_matrix(rp,2):ripple_matrix(rp,4)));
                    %                     app.Parameters.ripple.amp(rp) = abs(max_v-min_v);
                    app.Parameters.ripple.amp(rp) = min(CA1_LFP(ripple_matrix(rp,1),ripple_matrix(rp,2):ripple_matrix(rp,4)));

                end
            end

            % Delta
            % Pre-allocate the parameters
            app.Parameters.delta.num_integral = zeros(size(delta_matrix,1),1);
            app.Parameters.delta.sd_duration = zeros(size(delta_matrix,1),1);
            app.Parameters.delta.amp = zeros(size(delta_matrix,1),1);
            
            app.CA1_data_filtered_theta = eegfilt2(CA1_LFP,1000,5,9);
            
            % Check if the user informed the ripple matrix
            if ~isempty(delta_matrix)
                for dt = 1:size(delta_matrix,1)
                    % Numeric integral
                    app.Parameters.delta.num_integral(dt) = trapz(mPFC_LFP(delta_matrix(dt,1),delta_matrix(dt,2):delta_matrix(dt,4)));

                    % Calc SD/duration
                    app.Parameters.delta.sd_duration(dt) = std(mPFC_LFP(delta_matrix(dt,1),delta_matrix(dt,2):delta_matrix(dt,4)))...
                        / length(delta_matrix(dt,2):delta_matrix(dt,4));

                    % Calc amplitude (max-min)
                    max_v = max(mPFC_LFP(delta_matrix(dt,1),delta_matrix(dt,2):delta_matrix(dt,4)));
                    %                     min_v = min(mPFC_LFP(delta_matrix(dt,1),delta_matrix(dt,2):delta_matrix(dt,4)));
                    %                     app.Parameters.delta.amp(dt) = abs(max_v - min_v);
                    app.Parameters.delta.amp(dt) = max_v;
                    
                    % Theta rms
                    app.Parameters.delta.theta_rms(dt) = rms(app.CA1_data_filtered_theta(delta_matrix(dt,1),delta_matrix(dt,2):delta_matrix(dt,4)));
                end
            end
            
            % Spindle
            % Pre-allocate the parameters
            app.Parameters.spindle.zero_crossing = zeros(size(spindle_matrix,1),1);
            app.Parameters.spindle.num_integral = zeros(size(spindle_matrix,1),1);
            app.Parameters.spindle.sd_duration = zeros(size(spindle_matrix,1),1);
            
            % Check if the user informed the ripple matrix
            if ~isempty(spindle_matrix)
                for sp = 1:size(spindle_matrix,1)
                    % Zero-crossing
                    app.Parameters.spindle.zero_crossing(sp) = ZCR(mPFC_LFP(spindle_matrix(sp,1),spindle_matrix(sp,2):spindle_matrix(sp,4)));
                    
                    % Numeric integral
                    app.Parameters.spindle.num_integral(sp) = trapz(mPFC_LFP(spindle_matrix(sp,1),spindle_matrix(sp,2):spindle_matrix(sp,4)));
                    
                    % Calc SD/duration
                    app.Parameters.spindle.sd_duration(sp) = std(mPFC_LFP(spindle_matrix(sp,1),spindle_matrix(sp,2):spindle_matrix(sp,4)))...
                        / length(spindle_matrix(sp,2):spindle_matrix(sp,4));
                end
            end
            
            % Saves the All_Sort, LFPs and event matrices properties
            app.All_Sort = all_Sort;    % All_Sort
            app.CA1_data = CA1_LFP;     % CA1
            app.MPFC_data = mPFC_LFP;   % mPFC
            app.Event_matrix.ripple = ripple_matrix;    % Ripple
            app.Event_matrix.delta = delta_matrix;      % 
            app.Event_matrix.spindle = spindle_matrix;
            
            % Get the indices of the epochs associated to the
            % events
            app.Selected_Event_Type_Epochs = app.Event_matrix.ripple(:,1);
            
            clear CA1_LFP mPFC_LFP
            
            % Defines the ploted LFP as CA1 since the 'Event_type' drop box has the value 'Ripple' as default
            app.Ploted_LPF = app.CA1_data;
            app.X_axis_name = 'RMS';           % X axis label
            app.Y_axis_name = 'Amplitude';      % Y axis label
            app.X_axis_value = app.Parameters.ripple.amp; % Vector values (X)
            app.Y_axis_value = app.Parameters.ripple.RMS; % Vector values (Y)
            app.Event_timestamps = ripple_matrix(:,2:4);            % Get the event timestamps
            
            % Check if it is a row vector
            if isrow(app.Parameters.ripple.RMS)
                app.Parameters.ripple.RMS = app.Parameters.ripple.RMS';   % Change to a column vector
            end
            
            % Check if it is a row vector
            if isrow(app.Parameters.ripple.amp)
                app.Parameters.ripple.amp = app.Parameters.ripple.amp';       % Change to a column vector
            end
            
            % Concatenate both the parameters
            app.Grouped_axis_values = [app.Parameters.ripple.amp app.Parameters.ripple.RMS]; 
            
            % Normalize both axis before calculating the Euclidian distance
            app.Grouped_axis_values = zscore(app.Grouped_axis_values);
            app.X_axis_value = zscore(app.X_axis_value);
            app.Y_axis_value = zscore(app.Y_axis_value);

            % Define the selected LFP to be ploted
            app.Selected_LFP = app.CA1_data;

                 
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            % Get the current point (mouse location)
            coord(1,1) = app.UIScatter.CurrentPoint(1,1);
            coord(1,2) = app.UIScatter.CurrentPoint(1,2);
                        
            % Get the closest point using Euclidian distance
            closest_index = dsearchn(app.Grouped_axis_values,coord);
            
            %             % Select the periods according to the current points
            %             % coordinates
            %             distances = sqrt(sum(bsxfun(@minus, app.Grouped_axis_values, coord).^2,2)); % Compute Euclidean distances
            %             %             closest_index = app.Grouped_axis_values(find(distances==min(distances)),:);              % Find the smallest distance and use that as an index into Y_axis_value
            %             closest_index = find(distances==min(distances));              % Find the smallest distance and use that as an index into Y_axis_value
            
            % Get the scatter index
            selected_period_scatter = closest_index;
            % Get the LPF plot index =
            selected_period_lfp = app.Selected_Event_Type_Epochs(closest_index);
            % Get the event timestamps
            selected_event_timestamps = app.Event_timestamps(closest_index,:);
            
            % Call the function to update the plots
            update_plots(app,selected_period_lfp,selected_period_scatter,selected_event_timestamps)
        end

        % Value changed function: EventTypeDropDown
        function EventTypeDropDownValueChanged(app, event)
            % Change the app properties accordingly to the event type
            
            % Check the selected event type
            switch app.EventTypeDropDown.Value
 
 % Ripple
                case 'Ripple'           
                    app.Ploted_LPF = app.CA1_data;               % CA1_LFP
                    app.X_axis_name = 'Amplitude';           % X axis label
                    app.Y_axis_name = 'RMS';      % Y axis label
                    app.X_axis_value = app.Parameters.ripple.amp;     % Vector values (X)
                    app.Y_axis_value = app.Parameters.ripple.RMS;       % Vector values (Y)
                    app.Event_timestamps = app.Event_matrix.ripple(:,2:4);            % Get the event timestamps
                    
                    % Check if it is a row vector
                    if isrow(app.Parameters.ripple.RMS)
                        app.Parameters.ripple.RMS = app.Parameters.ripple.RMS';   % Change to a column vector
                    end
                    
                    % Check if it is a row vector
                    if isrow(app.Parameters.ripple.amp)
                        app.Parameters.ripple.amp = app.Parameters.ripple.amp';       % Change to a column vector
                    end
                    
                    % Concatenate both the parameters
                    app.Grouped_axis_values = [app.Parameters.ripple.amp app.Parameters.ripple.RMS];
  
                    % Get the indices of the epochs associated to the
                    % events
                    app.Selected_Event_Type_Epochs = app.Event_matrix.ripple(:,1);
                    
                    % Change the Plot Labels
                    app.UIScatter.XLabel.String = app.X_axis_name;  % X 
                    app.UIScatter.YLabel.String = app.Y_axis_name;  % Y

                    % Define the selected LFP to be ploted
                    app.Selected_LFP = app.CA1_data;
 % Delta                    
                case 'Delta'                    
                    app.Ploted_LPF = app.MPFC_data;                             % mPFC_LFP
                    app.X_axis_name = 'Integral';                          % X axis label
                    app.Y_axis_name = 'Amplitude';                               % Y axis label
                    app.X_axis_value = app.Parameters.delta.num_integral;        % Vector values (X)
                    app.Y_axis_value = app.Parameters.delta.amp;       % Vector values (Y)
                    app.Event_timestamps = app.Event_matrix.delta(:,2:4);            % Get the event timestamps
                    
                    % Check if it is a row vector
                    if isrow(app.Parameters.delta.sd_duration)
                        app.Parameters.delta.sd_duration = app.Parameters.delta.sd_duration';   % Change to a column vector
                    end
                    
                    % Check if it is a row vector
                    if isrow(app.Parameters.delta.num_integral)
                        app.Parameters.delta.num_integral = app.Parameters.delta.num_integral';       % Change to a column vector
                    end
                    
                    if isrow(app.Parameters.delta.theta_rms)
                        app.Parameters.delta.theta_rms = app.Parameters.delta.theta_rms';
                    end
                    
                    % Concatenate both the parameters
                    app.Grouped_axis_values = [app.Parameters.delta.num_integral app.Parameters.delta.amp];
                 
                    % Get the indices of the epochs associated to the
                    % events
                    app.Selected_Event_Type_Epochs = app.Event_matrix.delta(:,1);
                    
                    % Change the Plot Labels
                    app.UIScatter.XLabel.String = app.X_axis_name;  % X 
                    app.UIScatter.YLabel.String = app.Y_axis_name;  % Y

                    % Define the selected LFP to be ploted
                    app.Selected_LFP = app.MPFC_data;

 % Spindle                   
                case 'Spindle'
                    
                    app.Ploted_LPF = app.MPFC_data;                             % mPFC_LFP
                    app.X_axis_name = 'SD / Duration';                          % X axis label
                    app.Y_axis_name = 'Integral';                               % Y axis label
                    app.X_axis_value = app.Parameters.spindle.sd_duration;        % Vector values (X)
                    app.Y_axis_value = app.Parameters.spindle.num_integral;       % Vector values (Y)
                    app.Event_timestamps = app.Event_matrix.spindle(:,2:4);            % Get the event timestamps
                    
                    % Check if it is a row vector
                    if isrow(app.Parameters.spindle.sd_duration)
                        app.Parameters.spindle.sd_duration = app.Parameters.spindle.sd_duration';   % Change to a column vector
                    end
                    
                    % Check if it is a row vector
                    if isrow(app.Parameters.spindle.num_integral)
                        app.Parameters.spindle.num_integral = app.Parameters.spindle.num_integral';       % Change to a column vector
                    end
                    
                    % Concatenate both the parameters
                    app.Grouped_axis_values = [app.Parameters.spindle.sd_duration app.Parameters.spindle.num_integral];
            
                    % Get the indices of the epochs associated to the
                    % events
                    app.Selected_Event_Type_Epochs = app.Event_matrix.spindle(:,1);
                    
                    % Change the Plot Labels
                    app.UIScatter.XLabel.String = app.X_axis_name;  % X
                    app.UIScatter.YLabel.String = app.Y_axis_name;  % Y

                    % Define the selected LFP to be ploted
                    app.Selected_LFP = app.MPFC_data;

            end
            
            % Normalize both axis before calculating the Euclidian distance
            app.Grouped_axis_values = zscore(app.Grouped_axis_values);
            app.X_axis_value = zscore(app.X_axis_value);
            app.Y_axis_value = zscore(app.Y_axis_value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UI_LFP = uiaxes(app.UIFigure);
            title(app.UI_LFP, 'Title')
            xlabel(app.UI_LFP, 'X')
            ylabel(app.UI_LFP, 'Y')
            app.UIScatter = uiaxes(app.UIFigure);
            title(app.UIScatter, 'Title')
            xlabel(app.UIScatter, 'X')
            ylabel(app.UIScatter, 'Y')
            app.EventTypeDropDownLabel = uilabel(app.UIFigure);
            app.EventTypeDropDownLabel.Text = 'Event Type';
            app.EventTypeDropDown = uidropdown(app.UIFigure);
            app.EventTypeDropDown.Items = {'Ripple', 'Delta', 'Spindle'};
            app.EventTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EventTypeDropDownValueChanged, true);
            app.EventTypeDropDown.Value = 'Ripple';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Check_Ripple_Delta_Spindle(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end